classdef hw4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        JobSearchTab                   matlab.ui.container.Tab
        EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel  matlab.ui.control.Label
        BusinessCheckBox_2             matlab.ui.control.CheckBox
        InterestsLabel                 matlab.ui.control.Label
        SalesCheckBox                  matlab.ui.control.CheckBox
        LawCheckBox                    matlab.ui.control.CheckBox
        EducationServiceCheckBox       matlab.ui.control.CheckBox
        HealthcareCheckBox_2           matlab.ui.control.CheckBox
        FoodPersonalCareCheckBox       matlab.ui.control.CheckBox
        ProtectiveServicesCheckBox     matlab.ui.control.CheckBox
        SearchButton                   matlab.ui.control.Button
        EngineeringMathComputersCheckBox  matlab.ui.control.CheckBox
        FarmingFishingForestingCheckBox  matlab.ui.control.CheckBox
        ConstructionMaintenanceManufacturingCheckBox  matlab.ui.control.CheckBox
        ArtsEntertainmentSportsetcCheckBox  matlab.ui.control.CheckBox
        MilitaryCheckBox               matlab.ui.control.CheckBox
        ResultTestTextAreaLabel        matlab.ui.control.Label
        ResultTestTextArea             matlab.ui.control.TextArea
        SalaryBracketDropDownLabel     matlab.ui.control.Label
        SalaryBracketDropDown          matlab.ui.control.DropDown
        HighestLevelofEducationDropDownLabel  matlab.ui.control.Label
        HighestLevelofEducationDropDown  matlab.ui.control.DropDown
        JobInfoTab                     matlab.ui.container.Tab
        SearchbyjobtofindthecredentialsLabel  matlab.ui.control.Label
        EnterakeywordEditFieldLabel    matlab.ui.control.Label
        EnterakeywordEditField         matlab.ui.control.EditField
        SearchButton_3                 matlab.ui.control.Button
        EnterakeywordEditField_2Label  matlab.ui.control.Label
        EnterakeywordEditField_2       matlab.ui.control.EditField
        EnterakeywordEditField_3Label  matlab.ui.control.Label
        EnterakeywordEditField_3       matlab.ui.control.EditField
        Enteruptothree3kewordsLabel    matlab.ui.control.Label
    end


    properties (Access = public)
        data = csv_processing(); % data from csv file
        subset_data; % will hold data with criteria applied (OR WILL FAIL)
        edu_level = 8; % a code (1 - 8) describing level of education
        interests = []; % vector of ints that code for interests
        salary_bracket = [15000 30000]; % min and max salary
        key1 = ""; % words for user search function 
        key2 = "";
        key3 = "";
    end

    methods (Access = public)

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function
        function HighestLevelofEducationDropDownValueChanged(app, event)
            value = app.HighestLevelofEducationDropDown.Value;
            app.HighestLevelofEducationDropDown.ItemsData = [1:8];
            app.edu_level = value;
        end

        % Value changed function: BusinessCheckBox_2
        function BusinessCheckBox_2ValueChanged(app, event)
            value = app.BusinessCheckBox_2.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 1;
            end            
        end

        % Value changed function: SalesCheckBox
        function SalesCheckBoxValueChanged(app, event)
            value = app.SalesCheckBox.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 2;
            end
        end

        % Value changed function: LawCheckBox
        function LawCheckBoxValueChanged(app, event)
            value = app.LawCheckBox.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 3;
            end
        end

        % Value changed function: MilitaryCheckBox
        function MilitaryCheckBoxValueChanged(app, event)
            value = app.MilitaryCheckBox.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 4;
            end
        end

        % Value changed function: EducationServiceCheckBox
        function EducationServiceCheckBoxValueChanged(app, event)
            value = app.EducationServiceCheckBox.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 5;
            end
        end

        % Value changed function: HealthcareCheckBox_2
        function HealthcareCheckBox_2ValueChanged(app, event)
            value = app.HealthcareCheckBox_2.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 6;
            end
        end

        % Value changed function: FoodPersonalCareCheckBox
        function FoodPersonalCareCheckBoxValueChanged(app, event)
            value = app.FoodPersonalCareCheckBox.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 7;
            end
        end

        % Value changed function: ProtectiveServicesCheckBox
        function ProtectiveServicesCheckBoxValueChanged(app, event)
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 8;
            end
        end

        % Value changed function: EngineeringMathComputersCheckBox
        function EngineeringMathComputersCheckBoxValueChanged(app, event)
            value = app.EngineeringMathComputersCheckBox.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 9;
            end
        end

        % Value changed function: FarmingFishingForestingCheckBox
        function FarmingFishingForestingCheckBoxValueChanged(app, event)
            value = app.FarmingFishingForestingCheckBox.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 10;
            end
        end

        % Value changed function: 
        % ConstructionMaintenanceManufacturingCheckBox
        function ConstructionMaintenanceManufacturingCheckBoxValueChanged(app, event)
            value = app.ConstructionMaintenanceManufacturingCheckBox.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 11;
            end
        end

        % Value changed function: ArtsEntertainmentSportsetcCheckBox
        function ArtsEntertainmentSportsetcCheckBoxValueChanged(app, event)
            value = app.ArtsEntertainmentSportsetcCheckBox.Value;
            if(value == 1)
                size = length(app.interests);
                app.interests(size+1) = 12;
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            %app.subset_data = criteria_search(app.data, app.edu_level, app.salary_bracket(1), app.salary_bracket(2), app.interests);
            app.ResultTestTextArea.Value = num2str(app.edu_level);
        end

        % Button pushed function: SearchButton_3
        function SearchButton_3Pushed(app, event)
            key_vec = [app.key1];
            if (~isempty(app.key2))
                key_vec(length(keyvec)+1) = app.key2;
            end
            if (~isempty(app.key3))
                key_vec(length(keyvec)+1) = app.key3;
            end
            app.search_data = find_by_search(app.data, key_vec);
        end

        % Callback function
        function SalaryBracketButtonGroupSelectionChanged(app, event)
            % i deleted this component...?
        end

        % Value changed function: EnterakeywordEditField
        function EnterakeywordEditFieldValueChanged(app, event)
            app.key1 = app.EnterakeywordEditField.Value;
            
        end

        % Value changed function: EnterakeywordEditField_2
        function EnterakeywordEditField_2ValueChanged(app, event)
            app.key2 = app.EnterakeywordEditField_2.Value;
            
        end

        % Value changed function: EnterakeywordEditField_3
        function EnterakeywordEditField_3ValueChanged(app, event)
            app.key3 = app.EnterakeywordEditField_3.Value;
            
        end

        % Value changed function: SalaryBracketDropDown
        function SalaryBracketDropDownValueChanged(app, event)
            value = app.SalaryBracketDropDown.ItemsData;
            if (value == 1)
                app.salary_bracket = [15000 30000];
            elseif (value == 2)
                app.salary_bracket = [30000 50000];
            elseif (value == 3) 
                app.salary_bracket = [50000 100000];
            elseif (value == 4)
                app.salary_bracket = [100000 150000];
            elseif (value == 5)
                app.salary_bracket = [150000 100000000];
            end
        end

        % Value changed function: HighestLevelofEducationDropDown
        function eduLevelSwitch(app, event)
            value = app.HighestLevelofEducationDropDown.Value;
            app.edu_level = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.JobSearchTab = uitab(app.TabGroup);
            app.JobSearchTab.Title = 'Job Search';
            app.EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel = uilabel(app.JobSearchTab);
            app.EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel.Text = 'Enter your information and we''ll let you know what jobs would suit you! ';
            app.BusinessCheckBox_2 = uicheckbox(app.JobSearchTab);
            app.BusinessCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @BusinessCheckBox_2ValueChanged, true);
            app.BusinessCheckBox_2.Text = 'Business';
            app.InterestsLabel = uilabel(app.JobSearchTab);
            app.InterestsLabel.Text = 'Interests';
            app.SalesCheckBox = uicheckbox(app.JobSearchTab);
            app.SalesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SalesCheckBoxValueChanged, true);
            app.SalesCheckBox.Text = 'Sales';
            app.LawCheckBox = uicheckbox(app.JobSearchTab);
            app.LawCheckBox.ValueChangedFcn = createCallbackFcn(app, @LawCheckBoxValueChanged, true);
            app.LawCheckBox.Text = 'Law';
            app.EducationServiceCheckBox = uicheckbox(app.JobSearchTab);
            app.EducationServiceCheckBox.ValueChangedFcn = createCallbackFcn(app, @EducationServiceCheckBoxValueChanged, true);
            app.EducationServiceCheckBox.Text = 'Education & Service';
            app.HealthcareCheckBox_2 = uicheckbox(app.JobSearchTab);
            app.HealthcareCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @HealthcareCheckBox_2ValueChanged, true);
            app.HealthcareCheckBox_2.Text = 'Healthcare';
            app.FoodPersonalCareCheckBox = uicheckbox(app.JobSearchTab);
            app.FoodPersonalCareCheckBox.ValueChangedFcn = createCallbackFcn(app, @FoodPersonalCareCheckBoxValueChanged, true);
            app.FoodPersonalCareCheckBox.Text = 'Food & Personal Care';
            app.ProtectiveServicesCheckBox = uicheckbox(app.JobSearchTab);
            app.ProtectiveServicesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProtectiveServicesCheckBoxValueChanged, true);
            app.ProtectiveServicesCheckBox.Text = 'Protective Services';
            app.SearchButton = uibutton(app.JobSearchTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search!';
            app.EngineeringMathComputersCheckBox = uicheckbox(app.JobSearchTab);
            app.EngineeringMathComputersCheckBox.ValueChangedFcn = createCallbackFcn(app, @EngineeringMathComputersCheckBoxValueChanged, true);
            app.EngineeringMathComputersCheckBox.Text = 'Engineering, Math, Computers';
            app.FarmingFishingForestingCheckBox = uicheckbox(app.JobSearchTab);
            app.FarmingFishingForestingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FarmingFishingForestingCheckBoxValueChanged, true);
            app.FarmingFishingForestingCheckBox.Text = 'Farming, Fishing, Foresting';
            app.ConstructionMaintenanceManufacturingCheckBox = uicheckbox(app.JobSearchTab);
            app.ConstructionMaintenanceManufacturingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConstructionMaintenanceManufacturingCheckBoxValueChanged, true);
            app.ConstructionMaintenanceManufacturingCheckBox.Text = 'Construction, Maintenance, Manufacturing';
            app.ArtsEntertainmentSportsetcCheckBox = uicheckbox(app.JobSearchTab);
            app.ArtsEntertainmentSportsetcCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArtsEntertainmentSportsetcCheckBoxValueChanged, true);
            app.ArtsEntertainmentSportsetcCheckBox.Text = 'Arts, Entertainment, Sports, etc.';
            app.MilitaryCheckBox = uicheckbox(app.JobSearchTab);
            app.MilitaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @MilitaryCheckBoxValueChanged, true);
            app.MilitaryCheckBox.Text = 'Military';
            app.ResultTestTextAreaLabel = uilabel(app.JobSearchTab);
            app.ResultTestTextAreaLabel.Text = 'Result Test';
            app.ResultTestTextArea = uitextarea(app.JobSearchTab);
            app.SalaryBracketDropDownLabel = uilabel(app.JobSearchTab);
            app.SalaryBracketDropDownLabel.Text = 'Salary Bracket';
            app.SalaryBracketDropDown = uidropdown(app.JobSearchTab);
            app.SalaryBracketDropDown.Items = {'$15k-30k', '$30k-50k', '$50k-100k', '$100k-150k', '$150k+'};
            app.SalaryBracketDropDown.ItemsData = {'[1 2 3 4 5]'};
            app.SalaryBracketDropDown.ValueChangedFcn = createCallbackFcn(app, @SalaryBracketDropDownValueChanged, true);
            app.SalaryBracketDropDown.Value = '[1 2 3 4 5]';
            app.HighestLevelofEducationDropDownLabel = uilabel(app.JobSearchTab);
            app.HighestLevelofEducationDropDownLabel.Text = 'Highest Level of Education';
            app.HighestLevelofEducationDropDown = uidropdown(app.JobSearchTab);
            app.HighestLevelofEducationDropDown.Items = {'No Formal Education', 'High School Diploma or Equivalent', 'Some College, No Degree'};
            app.HighestLevelofEducationDropDown.ItemsData = {'[1]'};
            app.HighestLevelofEducationDropDown.ValueChangedFcn = createCallbackFcn(app, @eduLevelSwitch, true);
            app.HighestLevelofEducationDropDown.Value = '[1]';
            app.JobInfoTab = uitab(app.TabGroup);
            app.JobInfoTab.Title = 'Job Info';
            app.SearchbyjobtofindthecredentialsLabel = uilabel(app.JobInfoTab);
            app.SearchbyjobtofindthecredentialsLabel.Text = 'Search by job to find the credentials!';
            app.EnterakeywordEditFieldLabel = uilabel(app.JobInfoTab);
            app.EnterakeywordEditFieldLabel.Text = 'Enter a keyword';
            app.EnterakeywordEditField = uieditfield(app.JobInfoTab, 'text');
            app.EnterakeywordEditField.ValueChangedFcn = createCallbackFcn(app, @EnterakeywordEditFieldValueChanged, true);
            app.SearchButton_3 = uibutton(app.JobInfoTab, 'push');
            app.SearchButton_3.ButtonPushedFcn = createCallbackFcn(app, @SearchButton_3Pushed, true);
            app.SearchButton_3.Text = 'Search!';
            app.EnterakeywordEditField_2Label = uilabel(app.JobInfoTab);
            app.EnterakeywordEditField_2Label.Text = 'Enter a keyword';
            app.EnterakeywordEditField_2 = uieditfield(app.JobInfoTab, 'text');
            app.EnterakeywordEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterakeywordEditField_2ValueChanged, true);
            app.EnterakeywordEditField_3Label = uilabel(app.JobInfoTab);
            app.EnterakeywordEditField_3Label.Text = 'Enter a keyword';
            app.EnterakeywordEditField_3 = uieditfield(app.JobInfoTab, 'text');
            app.EnterakeywordEditField_3.ValueChangedFcn = createCallbackFcn(app, @EnterakeywordEditField_3ValueChanged, true);
            app.Enteruptothree3kewordsLabel = uilabel(app.JobInfoTab);
            app.Enteruptothree3kewordsLabel.Text = 'Enter up to three (3) kewords';
        end
    end

    methods (Access = public)

        % Construct app
        function app = hw4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end